classdef tubes1gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        GridLayout        matlab.ui.container.GridLayout
        GridLayout3       matlab.ui.container.GridLayout
        Image             matlab.ui.control.Image
        UIAxes            matlab.ui.control.UIAxes
        GridLayout2       matlab.ui.container.GridLayout
        ImageInputButton  matlab.ui.control.Button
        Tab2              matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImageInputButton
        function ImageInputButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp')  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                fullFileName = fullfile(path, file);
                
                % Read the image
                img = imread(fullFileName);
                
                % Display the image in the app's UI Image component (app.Image)
                app.Image.ImageSource = fullFileName;

                % If the image is RGB, convert it to grayscale for histogram analysis
                if size(img, 3) == 3
                    grayImg = rgb2gray(img);
                else
                    grayImg = img;
                end
                
                % Compute the histogram of the grayscale image
                imgprcs = ImageProcessor;
                histogram = imgprcs.calculateGrayscaledHistogram(img);
                
                % Plot the histogram in app.UIAxes
                bar(app.UIAxes, histogram);
                title(app.UIAxes, 'Image Histogram');
                xlabel(app.UIAxes, 'Pixel Intensity');
                ylabel(app.UIAxes, 'Frequency');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout = uigridlayout(app.Tab);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ImageInputButton = uibutton(app.GridLayout2, 'push');
            app.ImageInputButton.ButtonPushedFcn = createCallbackFcn(app, @ImageInputButtonPushed, true);
            app.ImageInputButton.Text = 'Image Input';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.GridLayout3);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tubes1gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end